classdef EEGmotifs < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        EEGmotifsUIFigure               matlab.ui.Figure
        FileMenu                        matlab.ui.container.Menu
        ExitMenu                        matlab.ui.container.Menu
        SelectLoadingButton             matlab.ui.control.Button
        SelectSavingButton              matlab.ui.control.Button
        SelectsourcefolderLabel         matlab.ui.control.Label
        SelectdestinationfolderLabel    matlab.ui.control.Label
        LaunchButton                    matlab.ui.control.Button
        ThresholdEditFieldLabel         matlab.ui.control.Label
        ThresholdEditField              matlab.ui.control.NumericEditField
        AnalysisTechniqueDropDownLabel  matlab.ui.control.Label
        AnalysisTechniqueDropDown       matlab.ui.control.DropDown
        NumberofmotifsDropDownLabel     matlab.ui.control.Label
        NumberofmotifsDropDown          matlab.ui.control.DropDown
        FeedbackArea                    matlab.ui.control.TextArea
        SourceLamp                      matlab.ui.control.Lamp
        DestinationLamp                 matlab.ui.control.Lamp
    end


    properties (Access = private)
        parameters;
        information;
        loading_path = "";
        saving_path = "";
        motif_number = 0;
        isLoading = 0;
        isSaving = 0;
    end

    methods (Access = private)
    
        function [is_ready] = isReady(app)
            if(app.isSaving && app.isLoading)
               app.ReadyLamp.Color =  [0,1,0];
               is_ready = 1;
            else
                app.ReadyLamp.Color = [1,0,0];
                is_ready = 0;
            end
        end
        
        % Will update the content of the GUI 
        function update_gui(app)
            
            
        end
    
        
        % Will save the content of the GUI Parameters
        function save_gui(app)
            
        end
        
        function write(app,message)
            app.FeedbackArea.Value = message;
        end
    
        function app_on_top(app)
            app.EEGmotifsUIFigure.Visible = 'off';
            app.EEGmotifsUIFigure.Visible = 'on';
        end
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Here will need to load from app_data
             % Setup
            base_directory = erase(mfilename('fullpath'),strcat(filesep,mfilename));
            
            % Setting up Variables
            app_data = load(strcat(base_directory,strcat(filesep,'..',filesep,'data',filesep,'app_data')));
            app.information = app_data.information;    
            app.parameters = app_data.parameters;
            
            write(app,"EEGMotifs is ready.");
        end

        % Menu selected function: ExitMenu
        function ExitMenuSelected(app, event)
            app.delete();
        end

        % Button pushed function: SelectLoadingButton
        function SelectLoadingButtonPushed(app, event)
            % Selecting a path
            selected_path = uigetdir;
            app_on_top(app);
            
            if isequal(selected_path,1)
               app.loading_path = selected_path;
               app.isLoading =  1;
               isReady(app);
            else
%
            end
        end

        % Button pushed function: SelectSavingButton
        function SelectSavingButtonPushed(app, event)
            selected_path = uigetdir;
            app.EEGmotifsUIFigure.Visible = 'off';
            app.EEGmotifsUIFigure.Visible = 'on';
            if isequal(selected_path,0)
                disp('User selected Cancel');
                app.isSaving = 0;
                app.saving_path ="";
                return;
            else
               app.saving_path = selected_path;
               app.isSaving = 1;
               isReady(app);
            end
        end

        % Button pushed function: LaunchButton
        function LaunchButtonPushed(app, event)
            if(is_ready(app))
                
                if(app.Button_motif3.Value == 1)
                    app.motif_number = 3;
                else
                    app.motif_number = 4;
                end
                
                if(app.dPLIButton.Value == 1)
                    type = 'dpli';
                else
                    type = 'nste';
                end
                
                threshold = app.ThresholdEditField.Value;
                calculate_all_motifs(app.loading_path,type,threshold,app.motif_number,app.saving_path);
                app.isSaving = 0;
                app.isLoading = 0;
                isReady(app);
                disp('Motif analysis terminated');
            else
                disp('Please select a saving and a loading directory');
            end
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.EEGmotifsUIFigure = uifigure;
            app.EEGmotifsUIFigure.Name = 'EEGmotifs';
            app.FileMenu = uimenu(app.EEGmotifsUIFigure);
            app.FileMenu.Text = 'File';
            app.ExitMenu = uimenu(app.FileMenu);
            app.ExitMenu.MenuSelectedFcn = createCallbackFcn(app, @ExitMenuSelected, true);
            app.ExitMenu.Text = 'Exit';
            app.SelectLoadingButton = uibutton(app.EEGmotifsUIFigure, 'push');
            app.SelectLoadingButton.ButtonPushedFcn = createCallbackFcn(app, @SelectLoadingButtonPushed, true);
            app.SelectLoadingButton.Text = 'Select';
            app.SelectSavingButton = uibutton(app.EEGmotifsUIFigure, 'push');
            app.SelectSavingButton.ButtonPushedFcn = createCallbackFcn(app, @SelectSavingButtonPushed, true);
            app.SelectSavingButton.Text = 'Select';
            app.SelectsourcefolderLabel = uilabel(app.EEGmotifsUIFigure);
            app.SelectsourcefolderLabel.Text = 'Select source folder';
            app.SelectdestinationfolderLabel = uilabel(app.EEGmotifsUIFigure);
            app.SelectdestinationfolderLabel.Text = 'Select destination folder';
            app.LaunchButton = uibutton(app.EEGmotifsUIFigure, 'push');
            app.LaunchButton.ButtonPushedFcn = createCallbackFcn(app, @LaunchButtonPushed, true);
            app.LaunchButton.Text = 'Launch';
            app.ThresholdEditFieldLabel = uilabel(app.EEGmotifsUIFigure);
            app.ThresholdEditFieldLabel.Text = 'Threshold';
            app.ThresholdEditField = uieditfield(app.EEGmotifsUIFigure, 'numeric');
            app.ThresholdEditField.Limits = [0 1];
            app.ThresholdEditField.Value = 0.1;
            app.AnalysisTechniqueDropDownLabel = uilabel(app.EEGmotifsUIFigure);
            app.AnalysisTechniqueDropDownLabel.Text = 'Analysis Technique';
            app.AnalysisTechniqueDropDown = uidropdown(app.EEGmotifsUIFigure);
            app.AnalysisTechniqueDropDown.Items = {'dPLI', 'NSTE'};
            app.AnalysisTechniqueDropDown.Value = 'dPLI';
            app.NumberofmotifsDropDownLabel = uilabel(app.EEGmotifsUIFigure);
            app.NumberofmotifsDropDownLabel.Text = 'Number of motifs';
            app.NumberofmotifsDropDown = uidropdown(app.EEGmotifsUIFigure);
            app.NumberofmotifsDropDown.Items = {'3', '4'};
            app.NumberofmotifsDropDown.Value = '3';
            app.FeedbackArea = uitextarea(app.EEGmotifsUIFigure);
            app.FeedbackArea.Editable = 'off';
            app.FeedbackArea.Value = {'Feedback Area'};
            app.SourceLamp = uilamp(app.EEGmotifsUIFigure);
            app.DestinationLamp = uilamp(app.EEGmotifsUIFigure);
        end
    end

    methods (Access = public)

        % Construct app
        function app = EEGmotifs

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.EEGmotifsUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.EEGmotifsUIFigure)
        end
    end
end